classdef MenschOderTier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HumanorAnimalUIFigure  matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        result                 matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Titelbild= imread('Titelbild.png');
            imshow(Titelbild,'parent',app.UIAxes);
            stopquestlg=0;
            stopcropped=0;
            tryagain=0;
            while stopquestlg==0
                selection = questdlg('What do you want to do?',...
                    'Choose',...
                    'Default images','Your images','Cancel','Cancel');
                switch selection,
                    case 'Cancel'
                        stopquestlg=1;
                    case 'Default images'
                        list = {'Image 1','Image 2','Image 3','Image 4','Image 5','Image 6','Image 7'...
                            'Image 8','Image 9','Image 10'};
                        [indx,tf] = listdlg('ListString',list,'SelectionMode','single');
                        if indx == 1
                            load ImageRecognitionNet.mat;
                            test = imread('test028003.png');
                            %test2= imresize(test,[630 630]);
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        elseif indx == 2
                            load ImageRecognitionNet.mat;
                            test = imread('test045009.png');
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        elseif indx == 3
                            load ImageRecognitionNet.mat;
                            test = imread('test047011.png');
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        elseif indx == 4
                            load ImageRecognitionNet.mat;
                            test = imread('test051005.png');
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        elseif indx == 5
                            load ImageRecognitionNet.mat;
                            test = imread('test052024.png');
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        elseif indx == 6
                            load ImageRecognitionNet.mat;
                            test = imread('test059002.png');
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        elseif indx == 7
                            load ImageRecognitionNet.mat;
                            test = imread('test062001.png');
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        elseif indx == 8
                            load ImageRecognitionNet.mat;
                            test = imread('test075002.png');
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        elseif indx == 9
                            load ImageRecognitionNet.mat;
                            test = imread('test106001.png');
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        elseif indx == 10
                            load ImageRecognitionNet.mat;
                            test = imread('test129002.png');
                            imshow(test,'parent',app.UIAxes)
                            test = uint8(test);
                            answer = classify(ImageRecognitionNet,test);
                            answer = char(answer);
                            app.result.Text = string(answer);
                        end
                    case 'Your images'
                        selection2 = questdlg('What type of images ?',...
                            'Choose',...
                            'Cropped images','Raw images','Raw images');
                        switch selection2,
                            case 'Cropped images'
                                stopcropped=0;
                                while stopcropped==0
                                    [namefile,pathname]=uigetfile('*.*','Select image'); %Such ein Bild aus
                                    if namefile~=0
                                        [test,map]=imread(strcat(pathname,namefile)); % Bild wird gelesen
                                        [x1,y1,z1] = size(test);
                                        if x1== 200 & y1 == 200
                                            imshow(test,'parent',app.UIAxes)
                                            test = uint8(test);
                                            load ImageRecognitionNet.mat;
                                            answer = classify(ImageRecognitionNet,test);
                                            answer = char(answer);
                                            app.result.Text = string(answer);
                                            stopcropped=1;
                                        else
                                            uiwait(msgbox('Please choose an image with the following dimensions: 200x200','False Dimensions','modal'));
                                            stopcropped=1;
                                        end
                                    end
                                end
                            case 'Raw images'
                                tryagain=0;
                                [namefile,pathname]=uigetfile('*.*','Select image');
                                if namefile~=0
                                    [img,map]=imread(strcat(pathname,namefile));
                                    imshow(img);
                                    while tryagain==0;
                                        objectRegion=round(getPosition(imrect));
                                        Bild = imcrop(img,objectRegion);
                                        GrauBild = rgb2gray(Bild);
                                        SchwarzweissBild = im2bw(GrauBild,0.94); % grau Bild in schwarzweiss
                                        WeissSchwarzBild = ~SchwarzweissBild;
                                        Objekte = regionprops(WeissSchwarzBild,'Image');
                                        for k = 1 : length(Objekte)
                                            BildObjekte = Objekte(k).Image;
                                            Groesse= size(BildObjekte);
                                            Groesse1 = Groesse(1);
                                            Groesse2 = Groesse(2);
                                            if Groesse1>= 150 & Groesse2 >= 150 % & Groesse1<= 2000
                                                BildSkaliert=imresize(BildObjekte,[200 200]);
                                                BildSkaliert = ~BildSkaliert;
                                                close figure 1;
                                                Bild=imresize(Bild,[500 500]);
                                                imshow(Bild,'parent',app.UIAxes);
                                                BildSkaliert = uint8(BildSkaliert);
                                                load ImageRecognitionNet.mat;
                                                answer = classify(ImageRecognitionNet,BildSkaliert);
                                                app.result.Text = string(answer);
                                                tryagain=1;
                                            end
                                        end
                                        if tryagain==0
                                            selection4 = questdlg('No object was found. Please try again with a bigger object.',...
                                                'Object not found',...
                                                'Try again','Cancel','Cancel');
                                            switch selection4,
                                                case 'Try again'
                                                case 'Cancel'
                                                    tryagain=1;
                                            end
                                        end
                                    end
                                end
                        end
                        if stopquestlg==0
                            selection3 = questdlg('What do you want to do next?',...
                                'Choose',...
                                'Try again','Cancel','Cancel');
                            switch selection3,
                                case 'Cancel'
                                    stopquestlg=1;
                            end
                        end
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HumanorAnimalUIFigure = uifigure;
            app.HumanorAnimalUIFigure.Name = 'Human or Animal ?';
            app.UIAxes = uiaxes(app.HumanorAnimalUIFigure);
            app.result = uilabel(app.HumanorAnimalUIFigure);
            app.result.Text = 'Ergebnis';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MenschOderTier

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HumanorAnimalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HumanorAnimalUIFigure)
        end
    end
end